classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Iac_maxEditField             matlab.ui.control.NumericEditField
        Iac_maxEditFieldLabel        matlab.ui.control.Label
        Vac_maxEditField             matlab.ui.control.NumericEditField
        Vac_maxEditFieldLabel        matlab.ui.control.Label
        Udc_maxEditField             matlab.ui.control.NumericEditField
        Udc_maxEditFieldLabel        matlab.ui.control.Label
        Iac_adc_maxIac_adc_minEditField  matlab.ui.control.NumericEditField
        Iac_adc_maxIac_adc_minEditFieldLabel  matlab.ui.control.Label
        Vac_adc_maxVac_adc_minEditField  matlab.ui.control.NumericEditField
        Vac_adc_maxVac_adc_minEditFieldLabel  matlab.ui.control.Label
        Udc_adcEditField             matlab.ui.control.NumericEditField
        Udc_adcEditFieldLabel        matlab.ui.control.Label
        Iac_adc_minEditField         matlab.ui.control.NumericEditField
        Iac_adc_minEditFieldLabel    matlab.ui.control.Label
        Iac_adc_maxEditField         matlab.ui.control.NumericEditField
        Iac_adc_maxEditFieldLabel    matlab.ui.control.Label
        Vac_adc_minEditField         matlab.ui.control.NumericEditField
        Vac_adc_minEditFieldLabel    matlab.ui.control.Label
        Vac_adc_maxEditField         matlab.ui.control.NumericEditField
        Vac_adc_maxEditFieldLabel    matlab.ui.control.Label
        P_3phaseWEditField           matlab.ui.control.NumericEditField
        P_3phaseWEditFieldLabel      matlab.ui.control.Label
        IacAmaxEditField             matlab.ui.control.NumericEditField
        IacAmaxEditFieldLabel        matlab.ui.control.Label
        IacArmsEditField             matlab.ui.control.NumericEditField
        IacArmsEditFieldLabel        matlab.ui.control.Label
        ROhmEditField                matlab.ui.control.NumericEditField
        ROhmEditFieldLabel           matlab.ui.control.Label
        DutyEditField                matlab.ui.control.NumericEditField
        DutyEditFieldLabel           matlab.ui.control.Label
        Vac_lineVmaxEditField        matlab.ui.control.NumericEditField
        Vac_lineVmaxEditFieldLabel   matlab.ui.control.Label
        Vac_phaseVmaxEditField       matlab.ui.control.NumericEditField
        Vac_phaseVmaxEditFieldLabel  matlab.ui.control.Label
        Vac_lineVrmsEditField        matlab.ui.control.NumericEditField
        Vac_lineVrmsEditFieldLabel   matlab.ui.control.Label
        Vac_phaseVrmsEditField       matlab.ui.control.NumericEditField
        Vac_phaseVrmsEditFieldLabel  matlab.ui.control.Label
        UdcVEditField                matlab.ui.control.NumericEditField
        UdcVEditFieldLabel           matlab.ui.control.Label
        P4WCONVERTERTOOLSTANDALONEMODELabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: UdcVEditField
        function UdcVEditFieldValueChanged(app, event)
            Udc_value = app.UdcVEditField.Value;
            Vac_rms = Udc_value * app.DutyEditField.Value;
            Iac_rms = Vac_rms / app.ROhmEditField.Value;
            Udc_max_value = app.Udc_maxEditField.Value;
            Vac_max_value = app.Vac_maxEditField.Value;
            Iac_max_value = app.Iac_maxEditField.Value;

            app.Vac_phaseVrmsEditField.Value = Vac_rms;
            app.Vac_phaseVmaxEditField.Value = Vac_rms * sqrt(2);

            app.Vac_lineVrmsEditField.Value = Vac_rms * sqrt(3);
            app.Vac_lineVmaxEditField.Value = Vac_rms * sqrt(6);

            app.IacArmsEditField.Value = Iac_rms;
            app.IacAmaxEditField.Value = Iac_rms * sqrt(2);

            app.P_3phaseWEditField.Value = Vac_rms * Iac_rms * 3;


            app.Vac_adc_maxEditField.Value = (Vac_rms / Vac_max_value) * 10000 * sqrt(2) + 10000;
            app.Vac_adc_minEditField.Value = 10000 - (Vac_rms / Vac_max_value) * 10000 * sqrt(2);

            app.Iac_adc_maxEditField.Value = (Iac_rms / Iac_max_value) * 10000 * sqrt(2) + 10000;
            app.Iac_adc_minEditField.Value = 10000 - (Iac_rms / Iac_max_value) * 10000 * sqrt(2);

            app.Vac_adc_maxVac_adc_minEditField.Value = app.Vac_adc_maxEditField.Value - app.Vac_adc_minEditField.Value;
            app.Iac_adc_maxIac_adc_minEditField.Value = app.Iac_adc_maxEditField.Value - app.Iac_adc_minEditField.Value;
            
            Vac_amplitude_adc = app.Vac_adc_maxEditField.Value - 10000;
            app.Udc_adcEditField.Value = (Udc_value / Udc_max_value) * 10000 + 10000; 

cla(app.UIAxes);  

% Khai báo biến
fs = 10000; % Tần số lấy mẫu
T = 0.02; % Chu kỳ 20ms
f = 1 / T; % Tần số sóng sin
t = 0:1/fs:3*T; % Thời gian lấy mẫu (3 chu kỳ)

% Lấy giá trị từ App Designer
Udc_graph = app.Udc_adcEditField.Value; % Giá trị đường thẳng Udc
Vac_max_graph = app.Vac_adc_maxEditField.Value; % Giá trị đỉnh trên của sin (V)
Vac_min_graph = app.Vac_adc_minEditField.Value; % Giá trị đỉnh dưới của sin (V)
Iac_max_graph = app.Iac_adc_maxEditField.Value; % Giá trị đỉnh trên của sin (I)
Iac_min_graph = app.Iac_adc_minEditField.Value; % Giá trị đỉnh dưới của sin (I)

% Tính biên độ và giá trị trung bình
A_V = (Vac_max_graph - Vac_min_graph) / 2; % Biên độ sóng sin điện áp
Offset_V = (Vac_max_graph + Vac_min_graph) / 2; % Giá trị trung bình điện áp

A_I = (Iac_max_graph - Iac_min_graph) / 2; % Biên độ sóng sin dòng điện
Offset_I = (Iac_max_graph + Iac_min_graph) / 2; % Giá trị trung bình dòng điện

% Tạo tín hiệu hình sin
y_sin_V = A_V * sin(2 * pi * f * t) + Offset_V; 
y_sin_I = A_I * sin(2 * pi * f * t) + Offset_I; 

% Vẽ đồ thị
hold(app.UIAxes, 'on'); 
plot(app.UIAxes, t, y_sin_V, 'b', 'LineWidth', 2); 
plot(app.UIAxes, t, y_sin_I, 'g', 'LineWidth', 2); 
yline(app.UIAxes, Udc_graph, 'r', 'LineWidth', 2); 

% Định dạng đồ thị
xlabel(app.UIAxes, 'Time (s)');
ylabel(app.UIAxes, 'Voltage / Current');
title(app.UIAxes, 'Voltage Vac-adc & Current Iac-adc & Voltage Udc-adc');
legend(app.UIAxes, 'Vac-adc', 'Iac-adc', 'Udc-adc'); 
grid(app.UIAxes, 'on');

hold(app.UIAxes, 'off');

        end

        % Value changed function: ROhmEditField
        function ROhmEditFieldValueChanged(app, event)
            Udc_value = app.UdcVEditField.Value;
            Vac_rms = Udc_value * app.DutyEditField.Value;
            Iac_rms = Vac_rms / app.ROhmEditField.Value;
            Udc_max_value = app.Udc_maxEditField.Value;
            Vac_max_value = app.Vac_maxEditField.Value;
            Iac_max_value = app.Iac_maxEditField.Value;

            app.Vac_phaseVrmsEditField.Value = Vac_rms;
            app.Vac_phaseVmaxEditField.Value = Vac_rms * sqrt(2);

            app.Vac_lineVrmsEditField.Value = Vac_rms * sqrt(3);
            app.Vac_lineVmaxEditField.Value = Vac_rms * sqrt(6);

            app.IacArmsEditField.Value = Iac_rms;
            app.IacAmaxEditField.Value = Iac_rms * sqrt(2);

            app.P_3phaseWEditField.Value = Vac_rms * Iac_rms * 3;


            app.Vac_adc_maxEditField.Value = (Vac_rms / Vac_max_value) * 10000 * sqrt(2) + 10000;
            app.Vac_adc_minEditField.Value = 10000 - (Vac_rms / Vac_max_value) * 10000 * sqrt(2);

            app.Iac_adc_maxEditField.Value = (Iac_rms / Iac_max_value) * 10000 * sqrt(2) + 10000;
            app.Iac_adc_minEditField.Value = 10000 - (Iac_rms / Iac_max_value) * 10000 * sqrt(2);

            app.Vac_adc_maxVac_adc_minEditField.Value = app.Vac_adc_maxEditField.Value - app.Vac_adc_minEditField.Value;
            app.Iac_adc_maxIac_adc_minEditField.Value = app.Iac_adc_maxEditField.Value - app.Iac_adc_minEditField.Value;
            
            Vac_amplitude_adc = app.Vac_adc_maxEditField.Value - 10000;
            app.Udc_adcEditField.Value = (Udc_value / Udc_max_value) * 10000 + 10000; 

cla(app.UIAxes);  

% Khai báo biến
fs = 10000; % Tần số lấy mẫu
T = 0.02; % Chu kỳ 20ms
f = 1 / T; % Tần số sóng sin
t = 0:1/fs:3*T; % Thời gian lấy mẫu (3 chu kỳ)

% Lấy giá trị từ App Designer
Udc_graph = app.Udc_adcEditField.Value; % Giá trị đường thẳng Udc
Vac_max_graph = app.Vac_adc_maxEditField.Value; % Giá trị đỉnh trên của sin (V)
Vac_min_graph = app.Vac_adc_minEditField.Value; % Giá trị đỉnh dưới của sin (V)
Iac_max_graph = app.Iac_adc_maxEditField.Value; % Giá trị đỉnh trên của sin (I)
Iac_min_graph = app.Iac_adc_minEditField.Value; % Giá trị đỉnh dưới của sin (I)

% Tính biên độ và giá trị trung bình
A_V = (Vac_max_graph - Vac_min_graph) / 2; % Biên độ sóng sin điện áp
Offset_V = (Vac_max_graph + Vac_min_graph) / 2; % Giá trị trung bình điện áp

A_I = (Iac_max_graph - Iac_min_graph) / 2; % Biên độ sóng sin dòng điện
Offset_I = (Iac_max_graph + Iac_min_graph) / 2; % Giá trị trung bình dòng điện

% Tạo tín hiệu hình sin
y_sin_V = A_V * sin(2 * pi * f * t) + Offset_V; 
y_sin_I = A_I * sin(2 * pi * f * t) + Offset_I; 

% Vẽ đồ thị
hold(app.UIAxes, 'on'); 
plot(app.UIAxes, t, y_sin_V, 'b', 'LineWidth', 2); 
plot(app.UIAxes, t, y_sin_I, 'g', 'LineWidth', 2); 
yline(app.UIAxes, Udc_graph, 'r', 'LineWidth', 2); 

% Định dạng đồ thị
xlabel(app.UIAxes, 'Time (s)');
ylabel(app.UIAxes, 'Voltage / Current');
title(app.UIAxes, 'Voltage Vac-adc & Current Iac-adc & Voltage Udc-adc');
legend(app.UIAxes, 'Vac-adc', 'Iac-adc', 'Udc-adc'); 
grid(app.UIAxes, 'on');

hold(app.UIAxes, 'off');

            
        end

        % Value changed function: Vac_phaseVrmsEditField
        function Vac_phaseVrmsEditFieldValueChanged(app, event)
            Vac_rms = app.Vac_phaseVrmsEditField.Value;
            Udc_value = Vac_rms / app.DutyEditField.Value;
            Iac_rms = Vac_rms / app.ROhmEditField.Value;
            Udc_max_value = app.Udc_maxEditField.Value;
            Vac_max_value = app.Vac_maxEditField.Value;
            Iac_max_value = app.Iac_maxEditField.Value;

            app.UdcVEditField.Value = Udc_value;
            app.Vac_phaseVmaxEditField.Value = Vac_rms * sqrt(2);

            app.Vac_lineVrmsEditField.Value = Vac_rms * sqrt(3);
            app.Vac_lineVmaxEditField.Value = Vac_rms * sqrt(6);

            app.IacArmsEditField.Value = Iac_rms;
            app.IacAmaxEditField.Value = Iac_rms * sqrt(2);

            app.P_3phaseWEditField.Value = Vac_rms * Iac_rms * 3;


            app.Vac_adc_maxEditField.Value = (Vac_rms / Vac_max_value) * 10000 * sqrt(2) + 10000;
            app.Vac_adc_minEditField.Value = 10000 - (Vac_rms / Vac_max_value) * 10000 * sqrt(2);

            app.Iac_adc_maxEditField.Value = (Iac_rms / Iac_max_value) * 10000 * sqrt(2) + 10000;
            app.Iac_adc_minEditField.Value = 10000 - (Iac_rms / Iac_max_value) * 10000 * sqrt(2);

            app.Vac_adc_maxVac_adc_minEditField.Value = app.Vac_adc_maxEditField.Value - app.Vac_adc_minEditField.Value;
            app.Iac_adc_maxIac_adc_minEditField.Value = app.Iac_adc_maxEditField.Value - app.Iac_adc_minEditField.Value;
            
            Vac_amplitude_adc = app.Vac_adc_maxEditField.Value - 10000;
            app.Udc_adcEditField.Value = (Udc_value / Udc_max_value) * 10000 + 10000; 

cla(app.UIAxes);  

% Khai báo biến
fs = 10000; % Tần số lấy mẫu
T = 0.02; % Chu kỳ 20ms
f = 1 / T; % Tần số sóng sin
t = 0:1/fs:3*T; % Thời gian lấy mẫu (3 chu kỳ)

% Lấy giá trị từ App Designer
Udc_graph = app.Udc_adcEditField.Value; % Giá trị đường thẳng Udc
Vac_max_graph = app.Vac_adc_maxEditField.Value; % Giá trị đỉnh trên của sin (V)
Vac_min_graph = app.Vac_adc_minEditField.Value; % Giá trị đỉnh dưới của sin (V)
Iac_max_graph = app.Iac_adc_maxEditField.Value; % Giá trị đỉnh trên của sin (I)
Iac_min_graph = app.Iac_adc_minEditField.Value; % Giá trị đỉnh dưới của sin (I)

% Tính biên độ và giá trị trung bình
A_V = (Vac_max_graph - Vac_min_graph) / 2; % Biên độ sóng sin điện áp
Offset_V = (Vac_max_graph + Vac_min_graph) / 2; % Giá trị trung bình điện áp

A_I = (Iac_max_graph - Iac_min_graph) / 2; % Biên độ sóng sin dòng điện
Offset_I = (Iac_max_graph + Iac_min_graph) / 2; % Giá trị trung bình dòng điện

% Tạo tín hiệu hình sin
y_sin_V = A_V * sin(2 * pi * f * t) + Offset_V; 
y_sin_I = A_I * sin(2 * pi * f * t) + Offset_I; 

% Vẽ đồ thị
hold(app.UIAxes, 'on'); 
plot(app.UIAxes, t, y_sin_V, 'b', 'LineWidth', 2); 
plot(app.UIAxes, t, y_sin_I, 'g', 'LineWidth', 2); 
yline(app.UIAxes, Udc_graph, 'r', 'LineWidth', 2); 

% Định dạng đồ thị
xlabel(app.UIAxes, 'Time (s)');
ylabel(app.UIAxes, 'Voltage / Current');
title(app.UIAxes, 'Voltage Vac-adc & Current Iac-adc & Voltage Udc-adc');
legend(app.UIAxes, 'Vac-adc', 'Iac-adc', 'Udc-adc'); 
grid(app.UIAxes, 'on');

hold(app.UIAxes, 'off');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Udc-adc, Vac-adc, Iac-adc')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.P4WCONVERTERTOOLSTANDALONEMODELabel = uilabel(app.UIFigure);
            app.P4WCONVERTERTOOLSTANDALONEMODELabel.Text = '3P4W CONVERTER TOOL | STANDALONE MODE';
            app.UdcVEditFieldLabel = uilabel(app.UIFigure);
            app.UdcVEditFieldLabel.Text = 'Udc (V)';
            app.UdcVEditField = uieditfield(app.UIFigure, 'numeric');
            app.UdcVEditField.ValueDisplayFormat = '%11.9g';
            app.UdcVEditField.ValueChangedFcn = createCallbackFcn(app, @UdcVEditFieldValueChanged, true);
            app.Vac_phaseVrmsEditFieldLabel = uilabel(app.UIFigure);
            app.Vac_phaseVrmsEditFieldLabel.Text = 'Vac_phase (Vrms)';
            app.Vac_phaseVrmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Vac_phaseVrmsEditField.ValueDisplayFormat = '%11.9g';
            app.Vac_phaseVrmsEditField.ValueChangedFcn = createCallbackFcn(app, @Vac_phaseVrmsEditFieldValueChanged, true);
            app.Vac_lineVrmsEditFieldLabel = uilabel(app.UIFigure);
            app.Vac_lineVrmsEditFieldLabel.Text = 'Vac_ line (Vrms)';
            app.Vac_lineVrmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Vac_lineVrmsEditField.ValueDisplayFormat = '%11.9g';
            app.Vac_phaseVmaxEditFieldLabel = uilabel(app.UIFigure);
            app.Vac_phaseVmaxEditFieldLabel.Text = 'Vac_phase (Vmax)';
            app.Vac_phaseVmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.Vac_phaseVmaxEditField.ValueDisplayFormat = '%11.9g';
            app.Vac_lineVmaxEditFieldLabel = uilabel(app.UIFigure);
            app.Vac_lineVmaxEditFieldLabel.Text = 'Vac_ line (Vmax)';
            app.Vac_lineVmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.Vac_lineVmaxEditField.ValueDisplayFormat = '%11.9g';
            app.DutyEditFieldLabel = uilabel(app.UIFigure);
            app.DutyEditFieldLabel.Text = 'Duty';
            app.DutyEditField = uieditfield(app.UIFigure, 'numeric');
            app.DutyEditField.ValueDisplayFormat = '%11.9g';
            app.DutyEditField.Value = 0.338461538461538;
            app.ROhmEditFieldLabel = uilabel(app.UIFigure);
            app.ROhmEditFieldLabel.Text = 'R (Ohm)';
            app.ROhmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROhmEditField.ValueDisplayFormat = '%11.9g';
            app.ROhmEditField.ValueChangedFcn = createCallbackFcn(app, @ROhmEditFieldValueChanged, true);
            app.ROhmEditField.Value = 100;
            app.IacArmsEditFieldLabel = uilabel(app.UIFigure);
            app.IacArmsEditFieldLabel.Text = 'Iac (Arms)';
            app.IacArmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IacArmsEditField.ValueDisplayFormat = '%11.9g';
            app.IacAmaxEditFieldLabel = uilabel(app.UIFigure);
            app.IacAmaxEditFieldLabel.Text = 'Iac (Amax)';
            app.IacAmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.IacAmaxEditField.ValueDisplayFormat = '%11.9g';
            app.P_3phaseWEditFieldLabel = uilabel(app.UIFigure);
            app.P_3phaseWEditFieldLabel.Text = 'P_3phase (W)';
            app.P_3phaseWEditField = uieditfield(app.UIFigure, 'numeric');
            app.P_3phaseWEditField.ValueDisplayFormat = '%11.9g';
            app.Vac_adc_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Vac_adc_maxEditFieldLabel.Text = 'Vac_adc_max';
            app.Vac_adc_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.Vac_adc_maxEditField.ValueDisplayFormat = '%11.9g';
            app.Vac_adc_minEditFieldLabel = uilabel(app.UIFigure);
            app.Vac_adc_minEditFieldLabel.Text = 'Vac_adc_min';
            app.Vac_adc_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.Vac_adc_minEditField.ValueDisplayFormat = '%11.9g';
            app.Iac_adc_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Iac_adc_maxEditFieldLabel.Text = 'Iac_adc_max';
            app.Iac_adc_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.Iac_adc_maxEditField.ValueDisplayFormat = '%11.9g';
            app.Iac_adc_minEditFieldLabel = uilabel(app.UIFigure);
            app.Iac_adc_minEditFieldLabel.Text = 'Iac_adc_min';
            app.Iac_adc_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.Iac_adc_minEditField.ValueDisplayFormat = '%11.9g';
            app.Udc_adcEditFieldLabel = uilabel(app.UIFigure);
            app.Udc_adcEditFieldLabel.Text = 'Udc_adc';
            app.Udc_adcEditField = uieditfield(app.UIFigure, 'numeric');
            app.Udc_adcEditField.ValueDisplayFormat = '%11.9g';
            app.Vac_adc_maxVac_adc_minEditFieldLabel = uilabel(app.UIFigure);
            app.Vac_adc_maxVac_adc_minEditFieldLabel.Text = 'Vac_adc_max - Vac_adc_min';
            app.Vac_adc_maxVac_adc_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.Vac_adc_maxVac_adc_minEditField.ValueDisplayFormat = '%11.9g';
            app.Iac_adc_maxIac_adc_minEditFieldLabel = uilabel(app.UIFigure);
            app.Iac_adc_maxIac_adc_minEditFieldLabel.Text = 'Iac_adc_max - Iac_adc_min';
            app.Iac_adc_maxIac_adc_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.Iac_adc_maxIac_adc_minEditField.ValueDisplayFormat = '%11.9g';
            app.Udc_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Udc_maxEditFieldLabel.Text = 'Udc_max';
            app.Udc_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.Udc_maxEditField.ValueDisplayFormat = '%11.9g';
            app.Udc_maxEditField.Value = 800;
            app.Vac_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Vac_maxEditFieldLabel.Text = 'Vac_max';
            app.Vac_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.Vac_maxEditField.ValueDisplayFormat = '%11.9g';
            app.Vac_maxEditField.Value = 400;
            app.Iac_maxEditFieldLabel = uilabel(app.UIFigure);
            app.Iac_maxEditFieldLabel.Text = 'Iac_max';
            app.Iac_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.Iac_maxEditField.ValueDisplayFormat = '%11.9g';
            app.Iac_maxEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end